classdef sss18_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        FunctionDropDownLabel         matlab.ui.control.Label
        FunctionDropDown              matlab.ui.control.DropDown
        ButtonGroup                   matlab.ui.container.ButtonGroup
        ContinuousButton              matlab.ui.control.RadioButton
        DiscreteButton                matlab.ui.control.RadioButton
        PlotButton                    matlab.ui.control.Button
        ElementaryButton_2            matlab.ui.control.StateButton
        PeriodicButton                matlab.ui.control.StateButton
        SelectanoptionLabel           matlab.ui.control.Label
        SamplingPeriodEditFieldLabel  matlab.ui.control.Label
        SamplingPeriodEditField       matlab.ui.control.NumericEditField
        FundamentalPeriodEditFieldLabel  matlab.ui.control.Label
        FundamentalPeriodEditField    matlab.ui.control.NumericEditField
        secLabel                      matlab.ui.control.Label
        secLabel_2                    matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        tvaluelimitsEditFieldLabel    matlab.ui.control.Label
        tvaluelimitsEditField         matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        IncrementEditFieldLabel       matlab.ui.control.Label
        IncrementEditField            matlab.ui.control.NumericEditField
        ShowgridsCheckBox             matlab.ui.control.CheckBox
        axisDropDownLabel             matlab.ui.control.Label
        axisDropDown                  matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EditField, IncrementEditField, 
        % PlotButton, tvaluelimitsEditField
        function PlotButtonPushed(app, event)
            view(app.UIAxes,2);
            if app.ElementaryButton_2.Value==1&&app.PeriodicButton.Value==0
                switch app.FunctionDropDown.Value
                    case 'Unit step'
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x=t>=0;
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Unit Step Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                low=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x=t>=0;
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Unit Step Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                        
                    case 'Rectangular pulse'
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x(t<3)=1;
                            x(t>=3)=0;
                            x(t<=-3)=0;
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Rectangular Pulse Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x(t<3)=1;
                            x(t>=3)=0;
                            x(t<=-3)=0;
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Rectangular Pulse Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                    case 'Signum'
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x=t./abs(t);
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Signum Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x=t./abs(t);
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Signum Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                    case 'Ramp'
                        
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x=(t>=0).*t;
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Ramp Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x=(t>=0).*t;
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Ramp Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                    case 'Complex exponential'
                        view(app.UIAxes,3);
                        if app.ShowgridsCheckBox==1
                            app.UIAxes.ZGrid=1;
                        end
                        if app.ContinuousButton.Value==1    
                            lowdef=-10;
                            updef=10;
                            incdef=0.001;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x=exp(1i*t);
                            plot3(app.UIAxes,t, real(x), imag(x), 'LineWidth',1);  %Continuous Complex exponential function
                            title(app.UIAxes, 'Continuous Complex Exponential Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'Real value of x(t)');
                            zlabel(app.UIAxes,'Imag value of x(t)');
                            
                        elseif app.DiscreteButton.Value==1 
                            lowdef=-10;
                            updef=10;
                            incdef=1;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x=exp(1i*t);
                            stem3(app.UIAxes,t, real(x), imag(x), 'LineWidth',1);
                            title(app.UIAxes, 'Discrete Complex Exponential Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'Real value of x(n)');
                            zlabel(app.UIAxes,'Imag value of x(n)');
                        end
                    case 'Real-valued exponential'
                        
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x=exp(-t);
                            x1=exp(t);
                            plot(app.UIAxes,t,x,t,x1);
                            title(app.UIAxes, 'Continuous Real-valued Exponential Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            legend(app.UIAxes,'Decaying','Rising');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t = low:inc:up;
                            t=t';
                            x = [exp(-t), exp(t)];
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Real-valued Exponential Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                            legend(app.UIAxes,'Decaying','Rising');
                        end
                    case 'Sinusoidal'
                        
                        if app.ContinuousButton.Value==1    
                            lowdef=-7;
                            updef=7;
                            incdef=0.001;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x=sin(pi*t/2);
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Sinusoidal Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-7;
                            updef=7;
                            incdef=1;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x=sin(pi*t/2);
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Sinusoidal Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                    case 'Causal exponential'
                        if app.ContinuousButton.Value==1    
                            lowdef=-3;
                            updef=3;
                            incdef=0.001;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x=(t>=0).*exp(t);
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Causal Exponential Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-3;
                            updef=3;
                            incdef=1;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x=(t>=0).*exp(t);
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Causal Exponential Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                    case 'Impulse'
                        if app.ContinuousButton.Value==1    
                            lowdef=-3;
                            updef=3;
                            incdef=0.001;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x= (t==0);
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Impulse Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-3;
                            updef=3;
                            incdef=1;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x= (t==0);
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Impulse Function');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                    
                end
            elseif app.PeriodicButton.Value==1&&app.ElementaryButton_2.Value==0
                switch app.FunctionDropDown.Value
                    case 'Sawtooth waveform'
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            fpdef=1;
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x = 2*t/fp - 2*ceil(t/fp-0.5);
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous sawtooth waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            fpdef=1;
                            
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x = 2*t/fp - 2*ceil(t/fp-0.5);
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Sawtooth Waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                    case 'Pulse waveform'
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            fpdef=1;
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            t1=mod(t,fp);
                            x=zeros(1,length(t));
                            x(t1<=pi/4)=1;
                            x(and(t1>pi/4,t1<=fp))=0;
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous pulse waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            fpdef=1;
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            t1=mod(t,fp);
                            x=zeros(1,length(t));
                            x(t1<=pi/4)=1;
                            x(and(t1>pi/4,t1<=fp))=0;
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete pulse waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                    case 'Square waveform'
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            fpdef=1;
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            
                            for i=1:length(t)
                                if mod(ceil(t(i)*2/fp), 2)==0
                                    x(i) = -1;
                                else
                                    x(i) = 1;
                                end
                            end
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous square waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            fpdef=1;
                            
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            for i=1:length(t)
                                if mod(ceil(t(i)*2/fp ), 2)==0
                                    x(i) = -1;
                                else
                                    x(i) = 1;
                                end
                            end
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Square waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                    case 'Triangular waveform'
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            fpdef=1;
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            
                            t1=mod(t,fp);
                            x=zeros(1,length(t));
                            x(t1<=fp/2)=2*t1(t1<=fp/2)/fp;
                            x(and(t1>fp/2,t1<=fp))=2*(1-t1(and(t1>fp/2,t1<=fp))/fp);
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Triangular waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            fpdef=1;
                            
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            t1=mod(t,fp);
                            x=zeros(1,length(t));
                            x(t1<=fp/2)=2*t1(t1<=fp/2)/fp;
                            x(and(t1>fp/2,t1<=fp))=2*(1-t1(and(t1>fp/2,t1<=fp))/fp);
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Triangular waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                            
                        end
                    case 'Sinusoidal waveform'
                        if app.ContinuousButton.Value==1    
                            lowdef=-5;
                            updef=5;
                            incdef=0.001;
                            fpdef=1;
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            if app.IncrementEditField.Value==0
                                app.tvaluelimitsEditField.Value=lowdef;
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                low=lowdef;
                                up=updef;
                                inc=incdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                                up=app.EditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=low:inc:up;
                            x=sin(2*pi*t/fp);
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Sinusoidal waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(t)');
                            
                        elseif app.DiscreteButton.Value==1  %Discrete unit step function
                            lowdef=-5;
                            updef=5;
                            incdef=1;
                            fpdef=1;
                            
                            if app.FundamentalPeriodEditField.Value<=0
                                app.FundamentalPeriodEditField.Value=fpdef;
                            end
                            fp=app.FundamentalPeriodEditField.Value;
                            
                            app.IncrementEditField.Value=0;
                            if app.SamplingPeriodEditField.Value<=0
                                app.SamplingPeriodEditField.Value=incdef;
                            end
                            
                            if app.tvaluelimitsEditField==0
                                low=lowdef;
                                app.tvaluelimitsEditField.Value=lowdef;
                            else
                                low=app.tvaluelimitsEditField.Value;
                            end
                            
                            if app.EditField.Value==0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value;
                            end
                            
                            inc=app.SamplingPeriodEditField.Value;
                            
                            t=low:inc:up;
                            x=sin(2*pi*t/fp);
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Sinusoidal waveform');
                            xlabel(app.UIAxes,'t');
                            ylabel(app.UIAxes,'x(n)');
                        end
                end
            end
            
        end

        % Value changed function: ElementaryButton_2
        function ElementaryButton_2ValueChanged(app, event)
            value = app.ElementaryButton_2.Value;
            if value==1
                app.FunctionDropDown.Items = {'Unit step', 'Rectangular pulse', 'Signum', 'Ramp','Complex exponential','Real-valued exponential','Sinusoidal','Causal exponential','Impulse'};
                app.PeriodicButton.Value=0;
                app.FunctionDropDown.Value='Unit step';
                app.FunctionDropDown.Enable=1;
                app.FundamentalPeriodEditField.Enable=0;
            elseif value==0
                app.FunctionDropDown.Items ={};
                app.FunctionDropDown.Enable=0;
            end
        end

        % Value changed function: PeriodicButton
        function PeriodicButtonValueChanged(app, event)
            value = app.PeriodicButton.Value;
            if value==1
                app.FunctionDropDown.Items = {'Sawtooth waveform', 'Pulse waveform', 'Square waveform', 'Triangular waveform','Sinusoidal waveform'};
                app.ElementaryButton_2.Value=0;
                app.FunctionDropDown.Value='Sawtooth waveform';
                app.FunctionDropDown.Enable=1;
                app.FundamentalPeriodEditField.Enable=1;
            else
                app.FunctionDropDown.Items ={};
                app.FunctionDropDown.Enable=0;
            end
        end

        % Value changed function: axisDropDown
        function axisDropDownValueChanged(app, event)
            axis(app.UIAxes,app.axisDropDown.Value);
            
        end

        % Value changed function: ShowgridsCheckBox
        function ShowgridsCheckBoxValueChanged(app, event)
            value = app.ShowgridsCheckBox.Value;
            app.UIAxes.ZGrid=1;
            if value==1
                app.UIAxes.XGrid=1;
                app.UIAxes.YGrid=1;
            else
                app.UIAxes.XGrid=0;
                app.UIAxes.YGrid=0;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {485, 485};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {237, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionDropDownLabel = uilabel(app.LeftPanel);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.LeftPanel);
            app.FunctionDropDown.Items = {};
            app.FunctionDropDown.Enable = 'off';
            app.FunctionDropDown.Value = {};
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ContinuousButton = uiradiobutton(app.ButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ContinuousButton.Value = true;
            app.DiscreteButton = uiradiobutton(app.ButtonGroup);
            app.DiscreteButton.Text = 'Discrete';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ElementaryButton_2 = uibutton(app.LeftPanel, 'state');
            app.ElementaryButton_2.ValueChangedFcn = createCallbackFcn(app, @ElementaryButton_2ValueChanged, true);
            app.ElementaryButton_2.Text = 'Elementary';
            app.PeriodicButton = uibutton(app.LeftPanel, 'state');
            app.PeriodicButton.ValueChangedFcn = createCallbackFcn(app, @PeriodicButtonValueChanged, true);
            app.PeriodicButton.Text = 'Periodic';
            app.SelectanoptionLabel = uilabel(app.LeftPanel);
            app.SelectanoptionLabel.Text = 'Select an option';
            app.SamplingPeriodEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingPeriodEditFieldLabel.Text = 'Sampling Period';
            app.SamplingPeriodEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FundamentalPeriodEditFieldLabel = uilabel(app.LeftPanel);
            app.FundamentalPeriodEditFieldLabel.Text = 'Fundamental Period';
            app.FundamentalPeriodEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FundamentalPeriodEditField.Enable = 'off';
            app.secLabel = uilabel(app.LeftPanel);
            app.secLabel.Text = 'sec';
            app.secLabel_2 = uilabel(app.LeftPanel);
            app.secLabel_2.Text = 'sec';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.tvaluelimitsEditFieldLabel = uilabel(app.RightPanel);
            app.tvaluelimitsEditFieldLabel.Text = 't value limits';
            app.tvaluelimitsEditField = uieditfield(app.RightPanel, 'numeric');
            app.tvaluelimitsEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.EditField = uieditfield(app.RightPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.IncrementEditFieldLabel = uilabel(app.RightPanel);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.RightPanel, 'numeric');
            app.IncrementEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.ShowgridsCheckBox = uicheckbox(app.RightPanel);
            app.ShowgridsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowgridsCheckBoxValueChanged, true);
            app.ShowgridsCheckBox.Text = 'Show grids';
            app.axisDropDownLabel = uilabel(app.RightPanel);
            app.axisDropDownLabel.Text = 'axis';
            app.axisDropDown = uidropdown(app.RightPanel);
            app.axisDropDown.Items = {'normal', 'equal', 'tight', 'square', 'fill'};
            app.axisDropDown.ValueChangedFcn = createCallbackFcn(app, @axisDropDownValueChanged, true);
            app.axisDropDown.Value = 'normal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sss18_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end