classdef sss18_sincurve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ButtonGroup                 matlab.ui.container.ButtonGroup
        ContinuousButton            matlab.ui.control.RadioButton
        DiscreteButton              matlab.ui.control.RadioButton
        PlotButton                  matlab.ui.control.Button
        SamplingrateEditFieldLabel  matlab.ui.control.Label
        SamplingrateEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel     matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.NumericEditField
        HzLabel_2                   matlab.ui.control.Label
        HzLabel                     matlab.ui.control.Label
        AmplitudeEditFieldLabel     matlab.ui.control.Label
        AmplitudeEditField          matlab.ui.control.NumericEditField
        PhaseEditFieldLabel         matlab.ui.control.Label
        PhaseEditField              matlab.ui.control.NumericEditField
        EditField                   matlab.ui.control.NumericEditField
        radLabel                    matlab.ui.control.Label
        noofcyclesLabel             matlab.ui.control.Label
        SinusoidalwaveLabel         matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        IncrementEditFieldLabel     matlab.ui.control.Label
        IncrementEditField          matlab.ui.control.NumericEditField
        ShowgridsCheckBox           matlab.ui.control.CheckBox
        Label                       matlab.ui.control.Label
        sss18Label                  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EditField, IncrementEditField, PlotButton
        function PlotButtonPushed(app, event)
                        if app.ContinuousButton.Value==1 
                            %phase
                            phase=app.PhaseEditField.Value;
                            updef=1;
                            incdef=0.001;
                            frdef=1;
                            ampdef=1;
                            
                            if app.AmplitudeEditField.Value<=0
                                amp=ampdef;
                                app.AmplitudeEditField.Value=ampdef;
                            else
                                amp=app.AmplitudeEditField.Value;
                            end
                            
                            if app.FrequencyEditField.Value<=0
                                app.FrequencyEditField.Value=frdef;
                            end
                            fr=app.FrequencyEditField.Value;
                            fp=1/fr;
                            if app.IncrementEditField.Value==0
                                
                                app.EditField.Value=updef;
                                app.IncrementEditField.Value=incdef;
                                
                                up=updef;
                                inc=incdef;
                            else
                                
                                up=app.EditField.Value/app.FrequencyEditField.Value;
                                inc=app.IncrementEditField.Value;
                            end
                            
                            t=0:inc:up;
                            x=amp*sin(2*pi*t/fp+phase);
                            plot(app.UIAxes,t,x);
                            title(app.UIAxes, 'Continuous Sinusoidal waveform');
                            xlabel(app.UIAxes,'t(sec)');
                            ylabel(app.UIAxes,'Amplitude,x(t)');
                            
                        elseif app.DiscreteButton.Value==1  %Discrete unit step function
                            app.Label.Text='';
                            updef=1/(app.FrequencyEditField.Value);%upperlimit
                            smfrdef=10;%default sampling rate
                            
                            %phase
                            phase=app.PhaseEditField.Value;
                            
                            %amplitude
                            ampdef=1;
                            if app.AmplitudeEditField.Value<=0
                                amp=ampdef;
                                app.AmplitudeEditField.Value=ampdef;
                            else
                                amp=app.AmplitudeEditField.Value;
                            end
                            
                            %frequency
                            frdef=1;
                            if app.FrequencyEditField.Value<=0
                                app.FrequencyEditField.Value=frdef;
                            end
                            fr=app.FrequencyEditField.Value;
                            fp=1/fr;
                            
                            if app.SamplingrateEditField.Value<=app.FrequencyEditField.Value
                                app.SamplingrateEditField.Value=smfrdef;
                                inc=1/smfrdef;
                                app.Label.Text='Please enter sampling rate greater than frequency';
                            else
                                inc=1/app.SamplingrateEditField.Value;
                            end
                            
                            if app.EditField.Value<=0%makes default if user enters frequency<=0
                                up=updef;
                                app.EditField.Value=updef;
                            else
                                up=app.EditField.Value/app.FrequencyEditField.Value;
                            end
                            
                            t=0:inc:up;
                            
                            x=amp*sin(2*pi*t/(fp)+phase);
                            stem(app.UIAxes,t,x);
                            title(app.UIAxes, 'Discrete Sinusoidal waveform');
                            xlabel(app.UIAxes,'t(sec)');
                            ylabel(app.UIAxes,'Amplitude,x(n)');
                        end
        end

        % Value changed function: ShowgridsCheckBox
        function ShowgridsCheckBoxValueChanged(app, event)
            value = app.ShowgridsCheckBox.Value;
            app.UIAxes.ZGrid=1;
            if value==1
                app.UIAxes.XGrid=1;
                app.UIAxes.YGrid=1;
            else
                app.UIAxes.XGrid=0;
                app.UIAxes.YGrid=0;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {485, 485};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {237, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ContinuousButton = uiradiobutton(app.ButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ContinuousButton.Value = true;
            app.DiscreteButton = uiradiobutton(app.ButtonGroup);
            app.DiscreteButton.Text = 'Discrete';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SamplingrateEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingrateEditField.Value = 10;
            app.FrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyEditField.Value = 1;
            app.HzLabel_2 = uilabel(app.LeftPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel = uilabel(app.LeftPanel);
            app.HzLabel.Text = 'Hz';
            app.AmplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudeEditField.Value = 1;
            app.PhaseEditFieldLabel = uilabel(app.LeftPanel);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.radLabel = uilabel(app.LeftPanel);
            app.radLabel.Text = 'rad';
            app.noofcyclesLabel = uilabel(app.LeftPanel);
            app.noofcyclesLabel.Text = 'no of cycles';
            app.SinusoidalwaveLabel = uilabel(app.LeftPanel);
            app.SinusoidalwaveLabel.Text = 'Sinusoidal wave';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.IncrementEditFieldLabel = uilabel(app.RightPanel);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.RightPanel, 'numeric');
            app.IncrementEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.ShowgridsCheckBox = uicheckbox(app.RightPanel);
            app.ShowgridsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowgridsCheckBoxValueChanged, true);
            app.ShowgridsCheckBox.Text = 'Show grids';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.sss18Label = uilabel(app.RightPanel);
            app.sss18Label.Text = '©sss18';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sss18_sincurve

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end