classdef sss18_audio_analyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        StatisticalparameterButtonGroup  matlab.ui.container.ButtonGroup
        MeanButton           matlab.ui.control.RadioButton
        VarianceButton       matlab.ui.control.RadioButton
        SkewnessButton       matlab.ui.control.RadioButton
        KurtosisButton       matlab.ui.control.RadioButton
        LoadButton           matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        TabGroup             matlab.ui.container.TabGroup
        PlotTab              matlab.ui.container.Tab
        UIAxes               matlab.ui.control.UIAxes
        ButtonGroup_2        matlab.ui.container.ButtonGroup
        GraphButton          matlab.ui.control.RadioButton
        HistogramButton      matlab.ui.control.RadioButton
        sss18Label           matlab.ui.control.Label
        ConfusionMatrixTab   matlab.ui.container.Tab
        UITable              matlab.ui.control.Table
        ActualsoundLabel     matlab.ui.control.Label
        PredictedsoundLabel  matlab.ui.control.Label
        TrainFanHumanLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        values = load("values.mat");
        testvalues = load("testing_values.mat");   
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            minimum = zeros(4,3);
            maximum = zeros(4,3);
            
            minimum(1,1) = app.values.meanOfTrain(100);
            minimum(1,2) = app.values.meanOfFan(100);
            minimum(1,3) = app.values.meanOfHuman(100);
            
            minimum(2,1) = app.values.varOfTrain(100);
            minimum(2,2) = app.values.varOfFan(100);
            minimum(2,3) = app.values.varOfHuman(100);
            
            minimum(3,1) = app.values.skewOfTrain(100);
            minimum(3,2) = app.values.skewOfFan(100);
            minimum(3,3) = app.values.skewOfHuman(100);
            
            minimum(4,1) = app.values.kurOfTrain(100);
            minimum(4,2) = app.values.kurOfFan(100);
            minimum(4,3) = app.values.kurOfHuman(100);
            
            
            maximum(1,1) = app.values.meanOfTrain(1700);
            maximum(1,2) = app.values.meanOfFan(1700);
            maximum(1,3) = app.values.meanOfHuman(1700);
            
            maximum(2,1) = app.values.varOfTrain(1700);
            maximum(2,2) = app.values.varOfFan(1700);
            maximum(2,3) = app.values.varOfHuman(1700);
            
            maximum(3,1) = app.values.skewOfTrain(1700);
            maximum(3,2) = app.values.skewOfFan(1700);
            maximum(3,3) = app.values.skewOfHuman(1700);
            
            maximum(4,1) = app.values.kurOfTrain(1500);
            maximum(4,2) = app.values.kurOfFan(1500);
            maximum(4,3) = app.values.kurOfHuman(1500);
            
            
            blocks = 1800;

            confOfMean = zeros(3,3);
            confOfVar = zeros(3,3);
            confOfSkew = zeros(3,3);
            confOfKur = zeros(3,3);
            
            %------------------------
            %Confusion matrix of mean
            meanValues = zeros(1800:3);
            meanValues(:,1) = app.testvalues.meanOfTrain1;
            meanValues(:,2) = app.testvalues.meanOfFan1;
            meanValues(:,3) = app.testvalues.meanOfHuman1;
            for i = 1:3
                for j = 1:3
                    for x = 1:blocks
                        if meanValues(x,j) < maximum(1,i) && meanValues(x,j) > minimum(1,i)
                            confOfMean(i,j) = confOfMean(i,j) + 1;
                        end
                    end
                end
            end
            
            %Confusion matrix of variance
            varValues = zeros(1800:3);
            varValues(:,1) = app.testvalues.varOfTrain1;
            varValues(:,2) = app.testvalues.varOfFan1;
            varValues(:,3) = app.testvalues.varOfHuman1;
            for i = 1:3
                for j = 1:3
                    for x = 1:blocks
                        if varValues(x,j) < maximum(2,i) && varValues(x,j) > minimum(2,i)
                            confOfVar(i,j) = confOfVar(i,j) + 1;
                        end
                    end
                end
            end
            
            %Confusion matrix of skewness
            skewValues = zeros(1800:3);
            skewValues(:,1) = app.testvalues.skewOfTrain1;
            skewValues(:,2) = app.testvalues.skewOfFan1;
            skewValues(:,3) = app.testvalues.skewOfHuman1;
            for i = 1:3
                for j = 1:3
                    for x = 1:blocks
                        if skewValues(x,j) < maximum(3,i) && skewValues(x,j) > minimum(3,i)
                            confOfSkew(i,j) = confOfSkew(i,j) + 1;
                        end
                    end
                end
            end
            
            %Confusion matrix of kurtosis
            kurValues = zeros(1800:3);
            kurValues(:,1) = app.testvalues.kurOfTrain1;
            kurValues(:,2) = app.testvalues.kurOfFan1;
            kurValues(:,3) = app.testvalues.kurOfHuman1;
            for i = 1:3
                for j = 1:3
                    for x = 1:blocks
                        if kurValues(x,j) < maximum(4,i) && kurValues(x,j) > minimum(4,i)
                            confOfKur(i,j) = confOfKur(i,j) + 1;
                        end
                    end
                end
            end
            
            
            if app.MeanButton.Value == 1
                if app.GraphButton.Value == 1
                    plot(app.UIAxes, app.values.x, app.values.meanOfTrain, app.values.x, app.values.meanOfFan, app.values.x,app.values.meanOfHuman);
                    grid(app.UIAxes,"on");
                    title(app.UIAxes,"Graph for mean");
                    xlabel(app.UIAxes,"Block number");
                    ylabel(app.UIAxes,"Mean");
                    legend(app.UIAxes,"Train","Fan","Human");
                elseif app.HistogramButton.Value == 1
                    histogram(app.UIAxes, app.values.meanOfTrain,80);
                end
                app.UITable.Data = confOfMean;
            elseif app.VarianceButton.Value == 1
                if app.GraphButton.Value == 1
                    plot(app.UIAxes, app.values.x, app.values.varOfTrain, app.values.x, app.values.varOfFan, app.values.x,app.values.varOfHuman);
                    grid(app.UIAxes,"on");
                    title(app.UIAxes,"Graph for variance");
                    xlabel(app.UIAxes,"Block number");
                    ylabel(app.UIAxes,"Variance");
                    legend(app.UIAxes,"Train","Fan","Human");
                elseif app.HistogramButton.Value == 1
                    histogram(app.UIAxes, app.values.varOfTrain,80);
                end
                app.UITable.Data = confOfVar;
            elseif app.SkewnessButton.Value == 1
                if app.GraphButton.Value == 1
                    plot(app.UIAxes, app.values.x, app.values.skewOfTrain, app.values.x, app.values.skewOfFan, app.values.x,app.values.skewOfHuman);
                    grid(app.UIAxes,"on");
                    title(app.UIAxes,"Graph for skewness");
                    xlabel(app.UIAxes,"Block number");
                    ylabel(app.UIAxes,"Skewness");
                    legend(app.UIAxes,"Train","Fan","Human");
                elseif app.HistogramButton.Value == 1
                    histogram(app.UIAxes, app.values.skewOfTrain,80);
                end
                app.UITable.Data = confOfSkew;
            elseif app.KurtosisButton.Value == 1
                if app.GraphButton.Value == 1
                    plot(app.UIAxes, app.values.x, app.values.kurOfTrain, app.values.x, app.values.kurOfFan, app.values.x,app.values.kurOfHuman);
                    grid(app.UIAxes,"on");
                    title(app.UIAxes,"Graph for kurtosis");
                    xlabel(app.UIAxes,"Block number");
                    ylabel(app.UIAxes,"Kurtosis");
                    legend(app.UIAxes,"Train","Fan","Human");
                elseif app.HistogramButton.Value == 1
                    histogram(app.UIAxes, app.values.kurOfTrain,80);
                end
                app.UITable.Data = confOfKur;
            end
            
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            LoadButtonPushed(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {188, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StatisticalparameterButtonGroup = uibuttongroup(app.LeftPanel);
            app.StatisticalparameterButtonGroup.Title = 'Statistical parameter';
            app.MeanButton = uiradiobutton(app.StatisticalparameterButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.VarianceButton = uiradiobutton(app.StatisticalparameterButtonGroup);
            app.VarianceButton.Text = 'Variance';
            app.SkewnessButton = uiradiobutton(app.StatisticalparameterButtonGroup);
            app.SkewnessButton.Text = 'Skewness';
            app.KurtosisButton = uiradiobutton(app.StatisticalparameterButtonGroup);
            app.KurtosisButton.Text = 'Kurtosis';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ButtonGroup_2 = uibuttongroup(app.PlotTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.GraphButton = uiradiobutton(app.ButtonGroup_2);
            app.GraphButton.Text = 'Graph';
            app.GraphButton.Value = true;
            app.HistogramButton = uiradiobutton(app.ButtonGroup_2);
            app.HistogramButton.Text = 'Histogram';
            app.sss18Label = uilabel(app.PlotTab);
            app.sss18Label.Text = '©sss18';
            app.ConfusionMatrixTab = uitab(app.TabGroup);
            app.ConfusionMatrixTab.Title = 'Confusion Matrix';
            app.UITable = uitable(app.ConfusionMatrixTab);
            app.UITable.ColumnName = {'Train'; 'Fan'; 'Human'};
            app.UITable.RowName = {};
            app.ActualsoundLabel = uilabel(app.ConfusionMatrixTab);
            app.ActualsoundLabel.Text = 'Actual sound';
            app.PredictedsoundLabel = uilabel(app.ConfusionMatrixTab);
            app.PredictedsoundLabel.Text = {'Predicted '; 'sound'};
            app.TrainFanHumanLabel = uilabel(app.ConfusionMatrixTab);
            app.TrainFanHumanLabel.Text = {'Train'; ''; ''; 'Fan'; ''; ''; 'Human'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sss18_audio_analyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end